classdef getbraininfo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GetBrainInfoUIFigure        matlab.ui.Figure
        BrainAreaDropDownLabel      matlab.ui.control.Label
        BrainAreaDropDown           matlab.ui.control.DropDown
        BregmaAPEditField           matlab.ui.control.NumericEditField
        BregmaMLEditField           matlab.ui.control.NumericEditField
        LambdaAPEditField           matlab.ui.control.NumericEditField
        LambdaMLEditField           matlab.ui.control.NumericEditField
        RelativetoBregmaLabel       matlab.ui.control.Label
        RelativetoLambdaLabel       matlab.ui.control.Label
        APLabel                     matlab.ui.control.Label
        MLLabel                     matlab.ui.control.Label
        APLabel_4                   matlab.ui.control.Label
        MLLabel_2                   matlab.ui.control.Label
        AddButton                   matlab.ui.control.Button
        SaveButton                  matlab.ui.control.Button
        CancelButton                matlab.ui.control.Button
        NewBrainAreaEditFieldLabel  matlab.ui.control.Label
        NewBrainAreaEditField       matlab.ui.control.EditField
        RelativetoMidlineLabel      matlab.ui.control.Label
        DVLabel                     matlab.ui.control.Label
        DVEditField                 matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        Info % Information on brain areas and their relative coordinates
        SaveFilesFolder % Folder where brainInfo.mat is saved
        CallingApp % Main app 
    end

    methods (Access = private)
    
        function changeinfo(app)
            brainAreas = app.BrainAreaDropDown.Items;
            value = app.BrainAreaDropDown.Value;
            idx = find(contains(brainAreas,value));
            app.Info.RelativeToBregma(idx,1) = app.BregmaAPEditField.Value;
            app.Info.RelativeToBregma(idx,2) = app.BregmaMLEditField.Value;
            app.Info.RelativeToLambda(idx,1) = app.LambdaAPEditField.Value;
            app.Info.RelativeToLambda(idx,2) = app.LambdaMLEditField.Value;
            app.Info.RelativeToMidlineDV(idx) = app.DVEditField.Value;
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, arg1, arg2)
            app.CallingApp = arg1;
            app.SaveFilesFolder = arg2;
            if exist([app.SaveFilesFolder,'\brainInfo.mat'],'file')
                info = load([app.SaveFilesFolder,'\brainInfo.mat']);
            else
                info.BrainArea = {'NBM','BLA','vHIPP','PFC'};
                info.RelativeToBregma = zeros(numel(info.BrainArea),2);
                info.RelativeToLambda = zeros(numel(info.BrainArea),2);
                info.RelativeToMidlineDV = zeros(numel(info.BrainArea),1);
            end
            app.BrainAreaDropDown.Items = info.BrainArea;
            app.BrainAreaDropDown.Value = info.BrainArea(1);
            app.BregmaAPEditField.Value = info.RelativeToBregma(1,1);
            app.BregmaMLEditField.Value = info.RelativeToBregma(1,2);
            app.LambdaAPEditField.Value = info.RelativeToLambda(1,1);
            app.LambdaMLEditField.Value = info.RelativeToLambda(1,2);
            app.DVEditField.Value = info.RelativeToMidlineDV(1);
            app.Info = info;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            BrainArea = app.Info.BrainArea;
            RelativeToBregma = app.Info.RelativeToBregma;
            RelativeToLambda = app.Info.RelativeToLambda;
            RelativeToMidlineDV = app.Info.RelativeToMidlineDV;
            save([app.SaveFilesFolder,'\brainInfo.mat'],'BrainArea','RelativeToBregma',...
                'RelativeToLambda','RelativeToMidlineDV') 
            % close(app.GetBrainInfoUIFigure)
            checkbraininfo(app.CallingApp)
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            checkbraininfo(app.CallingApp)
            delete(app)
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            if isempty(strtrim(app.NewBrainAreaEditField.Value))
                return;
            end
            newArea = {app.NewBrainAreaEditField.Value};
            app.Info.BrainArea(end+1) = newArea;
            app.BrainAreaDropDown.Items = app.Info.BrainArea;
            app.BrainAreaDropDown.Value = newArea;
            app.BregmaAPEditField.Value = 0;
            app.BregmaMLEditField.Value = 0;
            app.LambdaAPEditField.Value = 0;
            app.LambdaMLEditField.Value = 0;
            app.DVEditField.Value = 0;
            nAreas = numel(app.Info.BrainArea);
            app.Info.RelativeToBregma(nAreas,:) = [0,0];
            app.Info.RelativeToLambda(nAreas,:) = [0,0];
            app.Info.RelativeToMidlineDV(nAreas) = 0;
        end

        % Close request function: GetBrainInfoUIFigure
        function GetBrainInfoUIFigureCloseRequest(app, event)
            
            delete(app)
            
        end

        % Value changed function: BregmaAPEditField, 
        % BregmaMLEditField, DVEditField, LambdaAPEditField, 
        % LambdaMLEditField
        function BregmaAPEditFieldValueChanged(app, event)
            areas = app.BrainAreaDropDown.Items;
            site = app.BrainAreaDropDown.Value;
            idx = find(contains(areas,site));
            
            app.Info.RelativeToBregma(idx,1) = app.BregmaAPEditField.Value;
            app.Info.RelativeToBregma(idx,2) = app.BregmaMLEditField.Value;
            app.Info.RelativeToLambda(idx,1) = app.LambdaAPEditField.Value;
            app.Info.RelativeToLambda(idx,2) = app.LambdaMLEditField.Value;
            app.Info.RelativeToMidlineDV(idx) = app.DVEditField.Value;

            
        end

        % Value changed function: BrainAreaDropDown
        function BrainAreaDropDownValueChanged(app, event)
            areas = app.BrainAreaDropDown.Items;
            site = app.BrainAreaDropDown.Value;
            idx = find(contains(areas,site));

            app.BregmaAPEditField.Value = app.Info.RelativeToBregma(idx,1); 
            app.BregmaMLEditField.Value = app.Info.RelativeToBregma(idx,2); 
            app.LambdaAPEditField.Value = app.Info.RelativeToLambda(idx,1);
            app.LambdaMLEditField.Value = app.Info.RelativeToLambda(idx,2); 
            app.DVEditField.Value = app.Info.RelativeToMidlineDV(idx);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GetBrainInfoUIFigure = uifigure;
            app.GetBrainInfoUIFigure.Name = 'GetBrainInfo';
            app.GetBrainInfoUIFigure.CloseRequestFcn = createCallbackFcn(app, @GetBrainInfoUIFigureCloseRequest, true);
            app.BrainAreaDropDownLabel = uilabel(app.GetBrainInfoUIFigure);
            app.BrainAreaDropDownLabel.Text = 'Brain Area';
            app.BrainAreaDropDown = uidropdown(app.GetBrainInfoUIFigure);
            app.BrainAreaDropDown.Items = {'NBM', 'BLA', 'vHIPP', 'PFC'};
            app.BrainAreaDropDown.ValueChangedFcn = createCallbackFcn(app, @BrainAreaDropDownValueChanged, true);
            app.BrainAreaDropDown.Value = 'NBM';
            app.BregmaAPEditField = uieditfield(app.GetBrainInfoUIFigure, 'numeric');
            app.BregmaAPEditField.Limits = [-10 10];
            app.BregmaAPEditField.ValueDisplayFormat = '%1.3f';
            app.BregmaAPEditField.ValueChangedFcn = createCallbackFcn(app, @BregmaAPEditFieldValueChanged, true);
            app.BregmaMLEditField = uieditfield(app.GetBrainInfoUIFigure, 'numeric');
            app.BregmaMLEditField.Limits = [-10 10];
            app.BregmaMLEditField.ValueDisplayFormat = '%1.3f';
            app.BregmaMLEditField.ValueChangedFcn = createCallbackFcn(app, @BregmaAPEditFieldValueChanged, true);
            app.LambdaAPEditField = uieditfield(app.GetBrainInfoUIFigure, 'numeric');
            app.LambdaAPEditField.Limits = [-10 10];
            app.LambdaAPEditField.ValueDisplayFormat = '%1.3f';
            app.LambdaAPEditField.ValueChangedFcn = createCallbackFcn(app, @BregmaAPEditFieldValueChanged, true);
            app.LambdaMLEditField = uieditfield(app.GetBrainInfoUIFigure, 'numeric');
            app.LambdaMLEditField.Limits = [-10 10];
            app.LambdaMLEditField.ValueDisplayFormat = '%1.3f';
            app.LambdaMLEditField.ValueChangedFcn = createCallbackFcn(app, @BregmaAPEditFieldValueChanged, true);
            app.RelativetoBregmaLabel = uilabel(app.GetBrainInfoUIFigure);
            app.RelativetoBregmaLabel.Text = 'Relative to Bregma';
            app.RelativetoLambdaLabel = uilabel(app.GetBrainInfoUIFigure);
            app.RelativetoLambdaLabel.Text = 'Relative to Lambda';
            app.APLabel = uilabel(app.GetBrainInfoUIFigure);
            app.APLabel.Text = 'AP';
            app.MLLabel = uilabel(app.GetBrainInfoUIFigure);
            app.MLLabel.Text = 'ML';
            app.APLabel_4 = uilabel(app.GetBrainInfoUIFigure);
            app.APLabel_4.Text = 'AP';
            app.MLLabel_2 = uilabel(app.GetBrainInfoUIFigure);
            app.MLLabel_2.Text = 'ML';
            app.AddButton = uibutton(app.GetBrainInfoUIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.SaveButton = uibutton(app.GetBrainInfoUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CancelButton = uibutton(app.GetBrainInfoUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.NewBrainAreaEditFieldLabel = uilabel(app.GetBrainInfoUIFigure);
            app.NewBrainAreaEditFieldLabel.Text = 'New Brain Area';
            app.NewBrainAreaEditField = uieditfield(app.GetBrainInfoUIFigure, 'text');
            app.RelativetoMidlineLabel = uilabel(app.GetBrainInfoUIFigure);
            app.RelativetoMidlineLabel.Text = 'Relative to Midline';
            app.DVLabel = uilabel(app.GetBrainInfoUIFigure);
            app.DVLabel.Text = 'DV';
            app.DVEditField = uieditfield(app.GetBrainInfoUIFigure, 'numeric');
            app.DVEditField.Limits = [-10 10];
            app.DVEditField.ValueDisplayFormat = '%1.3f';
            app.DVEditField.ValueChangedFcn = createCallbackFcn(app, @BregmaAPEditFieldValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = getbraininfo(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GetBrainInfoUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GetBrainInfoUIFigure)
        end
    end
end