classdef getlogfilename < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Lookup                     matlab.ui.Figure
        NameLabel                  matlab.ui.control.Label
        NameDropDown               matlab.ui.control.DropDown
        MouseEditFieldLabel        matlab.ui.control.Label
        MouseEditField             matlab.ui.control.EditField
        SurgeryDateEditFieldLabel  matlab.ui.control.Label
        SurgeryDateEditField       matlab.ui.control.EditField
        CalButton                  matlab.ui.control.Button
        FileListDropDown           matlab.ui.control.DropDown
        ResetButton                matlab.ui.control.Button
        UpdateButton               matlab.ui.control.Button
        SpecifyLabel               matlab.ui.control.Label
        ChooseLabel                matlab.ui.control.Label
        AcceptButton               matlab.ui.control.Button
        CancelButton               matlab.ui.control.Button
    end


    properties (Access = public)
        CallingApp  % handle of the main app -- we use it to send the name of
                    % the selected file back
    end

    properties (Access = private)
        SelectedFile = []; % name of the selected file
        SurgeryDate  % list of surgery dates      
        MouseID % list of mouse IDs
        Filenames  % list of file names
        Names % list of the names of people doing surgery
        SaveFilesFolder % folder in which log files are saved
    end

    methods (Access = private)
    
        function names = updatelist(app)
            
            files = dir([app.SaveFilesFolder,'\Surgery*.mat']);
            filenames = cell(numel(files),1);
            names = filenames;
            mouseID = filenames;
            surgeryDate = filenames;
            for ii = 1:numel(files)
                filenames{ii} = files(ii).name;
                fn = files(ii).name;
                boo = strfind(fn,'_Date');
                bar = strfind(fn,'Name_');
                mouseID{ii} = fn(18:boo-1);
                surgeryDate{ii} = fn(boo+6:boo+11);
                names{ii} = fn(bar+5:end-4);
            end   
            app.Names = names;
            app.SurgeryDate = surgeryDate;
            app.MouseID = mouseID;
            app.Filenames = filenames;
            names = unique(names);
 
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, arg1, arg2)
            app.CallingApp = arg1;
            app.SaveFilesFolder = arg2;
            names = updatelist(app);
            app.NameDropDown.Items = [{'Any'};names];
            app.SelectedFile = 'none';
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.SelectedFile = [];
            app.NameDropDown.Value = 'Any';
            app.SurgeryDateEditField.Value = 'MM/DD/YY';
            app.MouseEditField.Value = '';
        end

        % Close request function: Lookup
        function LookupCloseRequest(app, event)
            
               % Send name of selected file back to the main app
               setlogfilename(app.CallingApp,app.SelectedFile);
       
               delete(app)
            
        end

        % Button pushed function: CalButton
        function CalButtonPushed(app, event)
            T = datestr(uigetdate(now),2);
            if ~isempty(T)
                app.SurgeryDateEditField.Value = T;
            end
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            mouseID = strtrim(app.MouseEditField.Value);
            surgeryDate = app.SurgeryDateEditField.Value;
            surgeryDate(strfind(surgeryDate,'/')) = [];
            name = app.NameDropDown.Value;
            
            fileIdx = 1:numel(app.Filenames);
            fileIdx1 = fileIdx;
            fileIdx2 = fileIdx;
            fileIdx3 = fileIdx;
            emptyMouseID = {'none','NA','no','any'};
            if ~any(strcmpi(mouseID,emptyMouseID)) && ~isempty(mouseID)
                mouseIDOkay = any(strcmpi(mouseID,app.MouseID));
                if ~mouseIDOkay
                    warndlg('No files match that mouse ID.');
                    return
                else
                    fileIdx1 = find(strcmpi(mouseID,app.MouseID));
                end
            end
            
            if ~strcmp(surgeryDate,'MMDDYY')
                surgeryDateOkay = any(strcmp(surgeryDate,app.SurgeryDate));
                if ~surgeryDateOkay
                    warndlg('No files match that surgery date.');
                    return
                else
                    fileIdx2 = find(strcmp(surgeryDate,app.SurgeryDate));
                end
            end
            
            if ~strcmp(name,'Any')
                nameOkay = any(strcmp(name,app.NameDropDown.Items));
                if ~nameOkay
                    warndlg('No files match that person.');
                    return
                else
                    fileIdx3 = find(strcmp(name,app.Names));
                end
            end
            
            foo = intersect(fileIdx1,fileIdx2);
            fileIdx = intersect(foo,fileIdx3);
            if isempty(fileIdx)
                warndlg('No files match that combination of mouseID, date, and name.');
                return
            else
                filenamesForDropDown = app.Filenames(fileIdx);
                ff = char(filenamesForDropDown);
                dateNums = zeros(numel(filenamesForDropDown),1);
                for jj = 1:numel(filenamesForDropDown)
                    bar = ff(jj,:);
                    baz = bar(strfind(bar,'Date_')+5:strfind(bar,'Date_')+10);
                    dateNums(jj) = datenum(baz,'MMDDYY');
                end
                [~,y] = sort(dateNums);
                filenamesForDropDown = filenamesForDropDown(y);
                for kk = 1:numel(filenamesForDropDown)
                    aa = filenamesForDropDown{kk};
                    aa = aa(18:end-4); % get rid of 'SurgeryLog_Mouse_' and '.mat'
                    roo = strfind(aa,'Date_');
                    aa(roo:roo+4) = [];
                    roo = strfind(aa,'Name_');
                    aa(roo:roo+4) = [];
                    filenamesForDropDown{kk} = aa;
                end
                app.FileListDropDown.Items = filenamesForDropDown;
            end
            
            
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.SelectedFile = [];
            close(app.Lookup)
        end

        % Button pushed function: AcceptButton
        function AcceptButtonPushed(app, event)
            filename = char(app.FileListDropDown.Value);
            underscores = strfind(filename,'_');
            filename = [filename(1:underscores(1)),'Date_',...
                filename(underscores(2)-6:underscores(2)),...
                'Name',filename(underscores(2):end),'.mat'];
            filename = ['SurgeryLog_Mouse_',filename];
            app.SelectedFile = filename;
            close(app.Lookup)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Lookup = uifigure;
            app.Lookup.Name = 'Lookup';
            app.Lookup.CloseRequestFcn = createCallbackFcn(app, @LookupCloseRequest, true);
            app.NameLabel = uilabel(app.Lookup);
            app.NameLabel.Text = 'Name';
            app.NameDropDown = uidropdown(app.Lookup);
            app.MouseEditFieldLabel = uilabel(app.Lookup);
            app.MouseEditFieldLabel.Text = 'Mouse';
            app.MouseEditField = uieditfield(app.Lookup, 'text');
            app.SurgeryDateEditFieldLabel = uilabel(app.Lookup);
            app.SurgeryDateEditFieldLabel.Text = 'Surgery Date';
            app.SurgeryDateEditField = uieditfield(app.Lookup, 'text');
            app.SurgeryDateEditField.Editable = 'off';
            app.SurgeryDateEditField.Value = 'MM/DD/YY';
            app.CalButton = uibutton(app.Lookup, 'push');
            app.CalButton.ButtonPushedFcn = createCallbackFcn(app, @CalButtonPushed, true);
            app.CalButton.Text = 'Cal';
            app.FileListDropDown = uidropdown(app.Lookup);
            app.FileListDropDown.Items = {''};
            app.FileListDropDown.Value = '';
            app.ResetButton = uibutton(app.Lookup, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.UpdateButton = uibutton(app.Lookup, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.SpecifyLabel = uilabel(app.Lookup);
            app.SpecifyLabel.Text = 'Specify';
            app.ChooseLabel = uilabel(app.Lookup);
            app.ChooseLabel.Text = 'Choose';
            app.AcceptButton = uibutton(app.Lookup, 'push');
            app.AcceptButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptButtonPushed, true);
            app.AcceptButton.Text = 'Accept';
            app.CancelButton = uibutton(app.Lookup, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
        end
    end

    methods (Access = public)

        % Construct app
        function app = getlogfilename(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Lookup)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Lookup)
        end
    end
end