classdef makevisible < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SurgeryLog       matlab.ui.Figure
        OpenButton       matlab.ui.control.Button
        SurgeryLogLabel  matlab.ui.control.Label
    end


    properties (Access = private)
         CallingApp % Description
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, arg1)
            
            app.CallingApp = arg1;
            app.CallingApp.RolemageLaboratoryUIFigure.Visible = 'off';
          
            
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            
            app.CallingApp.RolemageLaboratoryUIFigure.Visible = 'on';
            app.CallingApp.DateEditField.Value = datestr(now,2);
            
        end

        % Close request function: SurgeryLog
        function SurgeryLogCloseRequest(app, event)
           
             % delete(app)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SurgeryLog = uifigure;
            app.SurgeryLog.CloseRequestFcn = createCallbackFcn(app, @SurgeryLogCloseRequest, true);
            app.OpenButton = uibutton(app.SurgeryLog, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.SurgeryLogLabel = uilabel(app.SurgeryLog);
            app.SurgeryLogLabel.Text = 'Surgery Log';
        end
    end

    methods (Access = public)

        % Construct app
        function app = makevisible(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SurgeryLog)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SurgeryLog)
        end
    end
end