classdef surgerylog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RolemageLaboratoryUIFigure  matlab.ui.Figure
        NameDropDownLabel           matlab.ui.control.Label
        NameDropDown                matlab.ui.control.DropDown
        MouseIDLabel                matlab.ui.control.Label
        MouseIDEditField            matlab.ui.control.EditField
        DateEditFieldLabel          matlab.ui.control.Label
        DateEditField               matlab.ui.control.EditField
        SurgeryTypeDropDownLabel    matlab.ui.control.Label
        SurgeryTypeDropDown         matlab.ui.control.DropDown
        AnesthesiaDropDownLabel     matlab.ui.control.Label
        AnesthesiaDropDown          matlab.ui.control.DropDown
        StartTimeEditFieldLabel     matlab.ui.control.Label
        StartTimeEditField          matlab.ui.control.EditField
        StopTimeEditFieldLabel      matlab.ui.control.Label
        StopTimeEditField           matlab.ui.control.EditField
        APEditFieldLabel            matlab.ui.control.Label
        BregmaAPEditField           matlab.ui.control.NumericEditField
        MLEditFieldLabel            matlab.ui.control.Label
        BregmaMLEditField           matlab.ui.control.NumericEditField
        DVEditFieldLabel            matlab.ui.control.Label
        BregmaDVEditField           matlab.ui.control.NumericEditField
        DescriptionTextAreaLabel    matlab.ui.control.Label
        DescriptionTextArea         matlab.ui.control.TextArea
        BregmaLabel                 matlab.ui.control.Label
        LambdaLabel                 matlab.ui.control.Label
        MLEditField_2Label          matlab.ui.control.Label
        LambdaMLEditField           matlab.ui.control.NumericEditField
        DVEditField_2Label          matlab.ui.control.Label
        LambdaDVEditField           matlab.ui.control.NumericEditField
        SurgeryLogLabel             matlab.ui.control.Label
        TabGroup                    matlab.ui.container.TabGroup
        Site1Tab                    matlab.ui.container.Tab
        AreaDropDownLabel           matlab.ui.control.Label
        AreaDropDown_1              matlab.ui.control.DropDown
        MidlineDVEditFieldLabel     matlab.ui.control.Label
        MidlineDVEditField_1        matlab.ui.control.NumericEditField
        VirusEditField_4Label       matlab.ui.control.Label
        VirusEditField_1            matlab.ui.control.EditField
        FiberCheckBox_1             matlab.ui.control.CheckBox
        LensCheckBox_1              matlab.ui.control.CheckBox
        APEditField_3Label          matlab.ui.control.Label
        RelativeAPEditField_1       matlab.ui.control.NumericEditField
        MLEditField_3Label          matlab.ui.control.Label
        RelativeMLEditField_1       matlab.ui.control.NumericEditField
        DVEditField_3Label          matlab.ui.control.Label
        RelativeDVEditField_1       matlab.ui.control.NumericEditField
        APEditField_4Label          matlab.ui.control.Label
        AbsoluteAPEditField_1       matlab.ui.control.NumericEditField
        MLEditField_4Label          matlab.ui.control.Label
        AbsoluteMLEditField_1       matlab.ui.control.NumericEditField
        DVEditField_4Label          matlab.ui.control.Label
        AbsoluteDVEditField_1       matlab.ui.control.NumericEditField
        RelativeLabel_1             matlab.ui.control.Label
        AbsoluteLabel_1             matlab.ui.control.Label
        BilateralSwitch_1           matlab.ui.control.Switch
        LeftRightSwitch_1           matlab.ui.control.Switch
        LandmarkSwitch_1            matlab.ui.control.Switch
        Site2Tab                    matlab.ui.container.Tab
        FiberCheckBox_2             matlab.ui.control.CheckBox
        LensCheckBox_2              matlab.ui.control.CheckBox
        RelativeLabel_2             matlab.ui.control.Label
        AbsoluteLabel_2             matlab.ui.control.Label
        BilateralSwitch_2           matlab.ui.control.Switch
        LeftRightSwitch_2           matlab.ui.control.Switch
        LandmarkSwitch_2            matlab.ui.control.Switch
        AreaDropDown_5Label         matlab.ui.control.Label
        AreaDropDown_2              matlab.ui.control.DropDown
        MidlineDVEditField_8Label   matlab.ui.control.Label
        MidlineDVEditField_2        matlab.ui.control.NumericEditField
        VirusEditField_8Label       matlab.ui.control.Label
        VirusEditField_2            matlab.ui.control.EditField
        APEditField_17Label_3       matlab.ui.control.Label
        RelativeAPEditField_2       matlab.ui.control.NumericEditField
        MLEditField_17Label_3       matlab.ui.control.Label
        RelativeMLEditField_2       matlab.ui.control.NumericEditField
        DVEditField_17Label_3       matlab.ui.control.Label
        RelativeDVEditField_2       matlab.ui.control.NumericEditField
        APEditField_18Label_3       matlab.ui.control.Label
        AbsoluteAPEditField_2       matlab.ui.control.NumericEditField
        MLEditField_18Label_3       matlab.ui.control.Label
        AbsoluteMLEditField_2       matlab.ui.control.NumericEditField
        DVEditField_18Label_3       matlab.ui.control.Label
        AbsoluteDVEditField_2       matlab.ui.control.NumericEditField
        Site3Tab                    matlab.ui.container.Tab
        FiberCheckBox_3             matlab.ui.control.CheckBox
        LensCheckBox_3              matlab.ui.control.CheckBox
        RelativeLabel_3             matlab.ui.control.Label
        AbsoluteLabel_3             matlab.ui.control.Label
        BilateralSwitch_3           matlab.ui.control.Switch
        LeftRightSwitch_3           matlab.ui.control.Switch
        LandmarkSwitch_3            matlab.ui.control.Switch
        AreaDropDown_6Label         matlab.ui.control.Label
        AreaDropDown_3              matlab.ui.control.DropDown
        MidlineDVEditField_9Label   matlab.ui.control.Label
        MidlineDVEditField_3        matlab.ui.control.NumericEditField
        VirusEditField_9Label       matlab.ui.control.Label
        VirusEditField_3            matlab.ui.control.EditField
        APEditField_19Label         matlab.ui.control.Label
        RelativeAPEditField_3       matlab.ui.control.NumericEditField
        MLEditField_19Label         matlab.ui.control.Label
        RelativeMLEditField_3       matlab.ui.control.NumericEditField
        DVEditField_19Label         matlab.ui.control.Label
        RelativeDVEditField_3       matlab.ui.control.NumericEditField
        APEditField_20Label         matlab.ui.control.Label
        AbsoluteAPEditField_3       matlab.ui.control.NumericEditField
        MLEditField_20Label         matlab.ui.control.Label
        AbsoluteMLEditField_3       matlab.ui.control.NumericEditField
        DVEditField_20Label         matlab.ui.control.Label
        AbsoluteDVEditField_3       matlab.ui.control.NumericEditField
        Site4Tab                    matlab.ui.container.Tab
        FiberCheckBox_4             matlab.ui.control.CheckBox
        LensCheckBox_4              matlab.ui.control.CheckBox
        RelativeLabel_4             matlab.ui.control.Label
        AbsoluteLabel_4             matlab.ui.control.Label
        BilateralSwitch_4           matlab.ui.control.Switch
        LeftRightSwitch_4           matlab.ui.control.Switch
        LandmarkSwitch_4            matlab.ui.control.Switch
        AreaDropDown_7Label         matlab.ui.control.Label
        AreaDropDown_4              matlab.ui.control.DropDown
        MidlineDVEditField_10Label  matlab.ui.control.Label
        MidlineDVEditField_4        matlab.ui.control.NumericEditField
        VirusEditField_10Label      matlab.ui.control.Label
        VirusEditField_4            matlab.ui.control.EditField
        APEditField_21Label         matlab.ui.control.Label
        RelativeAPEditField_4       matlab.ui.control.NumericEditField
        MLEditField_21Label         matlab.ui.control.Label
        RelativeMLEditField_4       matlab.ui.control.NumericEditField
        DVEditField_21Label         matlab.ui.control.Label
        RelativeDVEditField_4       matlab.ui.control.NumericEditField
        APEditField_22Label         matlab.ui.control.Label
        AbsoluteAPEditField_4       matlab.ui.control.NumericEditField
        MLEditField_22Label         matlab.ui.control.Label
        AbsoluteMLEditField_4       matlab.ui.control.NumericEditField
        DVEditField_22Label         matlab.ui.control.Label
        AbsoluteDVEditField_4       matlab.ui.control.NumericEditField
        SaveLogButton               matlab.ui.control.Button
        SexDropDown                 matlab.ui.control.DropDown
        DOBEditFieldLabel           matlab.ui.control.Label
        DOBEditField                matlab.ui.control.EditField
        gEditFieldLabel             matlab.ui.control.Label
        WeightEditField             matlab.ui.control.NumericEditField
        AddNotesButton              matlab.ui.control.Button
        LookupButton                matlab.ui.control.Button
        KetorolacLabel              matlab.ui.control.Label
        KetorolacEditField          matlab.ui.control.NumericEditField
        mL01mL20gLabel              matlab.ui.control.Label
        APEditField_2Label          matlab.ui.control.Label
        LambdaAPEditField           matlab.ui.control.NumericEditField
        ZoomButton                  matlab.ui.control.Button
    end


    properties (Access = private)
        SelectedFile % Name of file selected in getlogfilename app
                     % to be used to fill in old information
        SaveFilesFolder % Folder where data files are to be saved
        BrainInfo % Information on brain areas and their coordinate relative to bregma and lambda
    end

    methods (Access = private)
    
        function updatesites(app)
          
            bregmaAP = app.BregmaAPEditField.Value;
            bregmaML = app.BregmaMLEditField.Value;
            lambdaAP = app.LambdaAPEditField.Value;
            lambdaML = app.LambdaMLEditField.Value;
            
            for iTabs = 1:4
                if strcmp(app.(['LandmarkSwitch_',num2str(iTabs)]).Value,'Bregma')
                    referenceAP = bregmaAP;
                    referenceML = bregmaML;
                else
                    referenceAP = lambdaAP;
                    referenceML = lambdaML;
                end
                APName1 = ['RelativeAPEditField_',num2str(iTabs)];
                APName2 = ['AbsoluteAPEditField_',num2str(iTabs)];
                MLName1 = ['RelativeMLEditField_',num2str(iTabs)];
                MLName2 = ['AbsoluteMLEditField_',num2str(iTabs)];
                DVName1 = ['RelativeDVEditField_',num2str(iTabs)];
                DVName2 = ['AbsoluteDVEditField_',num2str(iTabs)];
                MidlineName = ['MidlineDVEditField_',num2str(iTabs)];
                app.(APName2).Value = referenceAP - app.(APName1).Value;
                app.(MLName2).Value = referenceML - app.(MLName1).Value;
                app.(DVName2).Value = app.(MidlineName).Value - app.(DVName1).Value;
            end
       
        end
        
        function populateGUIwithsavedvalues(app)
            selectedFile = app.SelectedFile;
            if (numel(selectedFile)<10) || ~strcmp(selectedFile(end-2:end),'mat')
                return
            end
            load([app.SaveFilesFolder,'\',selectedFile],'out')
            name = out.name;
            if ~ismember(app.NameDropDown.Items,name)
                app.NameDropDown.Items{end+1} = name;
            end
            app.NameDropDown.Value = name;
            app.DateEditField.Value = out.surgeryDate;
            app.MouseIDEditField.Value = out.mouseID;
            app.WeightEditField.Value = out.mouseWeight;
            app.SexDropDown.Value = out.mouseSex;
            app.DOBEditField.Value = out.mouseDOB;
            app.StartTimeEditField.Value = out.surgeryStartTime;
            app.StopTimeEditField.Value = out.surgeryStopTime;
            app.AnesthesiaDropDown.Value = out.anesthesia;
            app.SurgeryTypeDropDown.Value = out.surgeryType;
            app.KetorolacEditField.Value = out.ketorolac;
            app.DescriptionTextArea.Value = out.description;
            app.BregmaAPEditField.Value = out.bregmaPosition(1);
            app.BregmaMLEditField.Value = out.bregmaPosition(2);
            app.BregmaDVEditField.Value = out.bregmaPosition(3);
            app.LambdaAPEditField.Value = out.lambdaPosition(1);
            app.LambdaMLEditField.Value = out.lambdaPosition(2);
            app.LambdaDVEditField.Value = out.lambdaPosition(3);
  
            for iTabs = 1:4
                app.(['AreaDropDown_',num2str(iTabs)]).Value = out.site(iTabs).Area;
                app.(['BilateralSwitch_',num2str(iTabs)]).Value = out.site(iTabs).bilateral;
                app.(['MidlineDVEditField_',num2str(iTabs)]).Value = out.site(iTabs).midlineDV;
                app.(['VirusEditField_',num2str(iTabs)]).Value = string(out.site(iTabs).virus);
                app.(['FiberCheckBox_',num2str(iTabs)]).Value = out.site(iTabs).fiber;
                app.(['LensCheckBox_',num2str(iTabs)]).Value = out.site(iTabs).lens;
                app.(['RelativeAPEditField_',num2str(iTabs)]).Value = out.site(iTabs).relativeAP;
                app.(['AbsoluteAPEditField_',num2str(iTabs)]).Value = out.site(iTabs).absoluteAP;
                app.(['RelativeMLEditField_',num2str(iTabs)]).Value = out.site(iTabs).relativeML;
                app.(['AbsoluteMLEditField_',num2str(iTabs)]).Value = out.site(iTabs).absoluteML;
                app.(['RelativeDVEditField_',num2str(iTabs)]).Value = out.site(iTabs).relativeDV;
                app.(['AbsoluteDVEditField_',num2str(iTabs)]).Value = out.site(iTabs).absoluteDV;
            end
            
        end
        
        function updatebraininfo(app,loadSavedInfo)
            
            if loadSavedInfo   %  load from brainInfo.mat
                info = load([app.SaveFilesFolder,'\brainInfo.mat']);
                info.BrainArea = [{'None'},info.BrainArea,{'Add / Edit'}];
                app.BrainInfo = info;
            end
            info = app.BrainInfo;
            for iTabs = 1:4    
                areas = app.(['AreaDropDown_',num2str(iTabs)]).Items;
                value = app.(['AreaDropDown_',num2str(iTabs)]).Value;
                idx = find(contains(areas,value));
                app.(['AreaDropDown_',num2str(iTabs)]).Items = info.BrainArea;       
                if idx==1   % None
                    app.(['RelativeAPEditField_',num2str(iTabs)]).Value = 0;
                    app.(['RelativeMLEditField_',num2str(iTabs)]).Value = 0;
                    app.(['RelativeDVEditField_',num2str(iTabs)]).Value = 0;
                elseif idx==numel(areas)
                    if numel(areas)==numel(info.BrainArea)
                        idx = 2;
                    end
                    app.(['AreaDropDown_',num2str(iTabs)]).Value = info.BrainArea(idx);
                end
                ref = app.(['LandmarkSwitch_',num2str(iTabs)]).Value;
                if idx > 1
                    if strcmp(ref,'Bregma')
                        app.(['RelativeAPEditField_',num2str(iTabs)]).Value = ...
                            info.RelativeToBregma(idx-1,1);
                        app.(['RelativeMLEditField_',num2str(iTabs)]).Value = ...
                            info.RelativeToBregma(idx-1,2);
                    else
                        app.(['RelativeAPEditField_',num2str(iTabs)]).Value = ...
                            info.RelativeToLambda(idx-1,1);
                        app.(['RelativeMLEditField_',num2str(iTabs)]).Value = ...
                            info.RelativeToLambda(idx-1,2);
                    end
                    app.(['RelativeDVEditField_',num2str(iTabs)]).Value = ...
                        info.RelativeToMidlineDV(idx-1);
                end
            end
            updatesites(app)  
        end
        
    end

    methods (Access = public)
    
        function setlogfilename(app,selectedFile)
                 app.SelectedFile = selectedFile;
                 populateGUIwithsavedvalues(app)
        end
        
        function checkbraininfo(app)
            
            if exist([app.SaveFilesFolder,'\brainInfo.mat'],'file')
                loadSavedInfo = true;
            else
                loadSavedInfo = false;
            end
            updatebraininfo(app,loadSavedInfo)
             
            
        end
        
        function updatefromzoom(app,newCoordinates)
            
            app.BregmaAPEditField.Value = newCoordinates.BregmaAP;
            app.BregmaMLEditField.Value = newCoordinates.BregmaML;
            app.BregmaDVEditField.Value = newCoordinates.BregmaDV;
            app.LambdaAPEditField.Value = newCoordinates.LambdaAP;
            app.LambdaMLEditField.Value = newCoordinates.LambdaML;
            app.LambdaDVEditField.Value = newCoordinates.LambdaDV;
            fStr = ['MidlineDVEditField_',newCoordinates.siteNo];
            app.(fStr).Value = newCoordinates.MidlineDV;
            updatesites(app)
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                        
            app.DateEditField.Value = datestr(now,2);
            if isdeployed && ispc
                foo = winqueryreg('HKEY_CURRENT_USER',...
                    ['Software\Microsoft\Windows\CurrentVersion\',...
                    'Explorer\Shell Folders'],'Personal');
                saveFilesFolder = [foo,'\SurgeryLogData'];
            else
                saveFilesFolder = [userpath,'\SurgeryLog\SurgeryLogData'];
            end
            if ~exist(saveFilesFolder,'dir')
                mkdir(saveFilesFolder)
            end
            if exist([saveFilesFolder,'\namesOfSurgeons.mat'],'file')
                boo = load([saveFilesFolder,'\namesOfSurgeons.mat']);
                app.NameDropDown.Items = boo.names;
            end
            app.SaveFilesFolder = saveFilesFolder;
            if exist([saveFilesFolder,'\brainInfo.mat'],'file')
                loadSavedInfo = true;
                updatebraininfo(app,loadSavedInfo)
            else
                app.BrainInfo.BrainArea = {'None','NBM','BLA','vHIPP','PFC','Add / Edit'};
                app.BrainInfo.RelativeToBregma = zeros(numel(app.BrainInfo.BrainArea),2);
                app.BrainInfo.RelativeToLambda = zeros(numel(app.BrainInfo.BrainArea),2);
                app.BrainInfo.RelativeToMidlineDV = zeros(numel(app.BrainInfo.BrainArea),1);
            end      
            
%             makevisible(app)

        end

        % Value changed function: NameDropDown
        function NameDropDownValueChanged(app, event)
            if strcmp(app.NameDropDown.Value,'Add / Delete')
                names = namesdialog(app.NameDropDown.Items);
                app.NameDropDown.Items = names;
                app.NameDropDown.Value = names(1);
                save([app.SaveFilesFolder,'\namesOfSurgeons.mat'],'names');
            end
        end

        % Button pushed function: AddNotesButton
        function AddNotesButtonPushed(app, event)
            mouseID = app.MouseIDEditField.Value;
            surgeryDate = app.DateEditField.Value;
            name = app.NameDropDown.Value;
            if contains(mouseID,'enter') || isempty(strtrim(mouseID))
                warndlg('Enter a mouse ID first.')
                return
            end
            formattedDate = [datestr(surgeryDate,5),datestr(surgeryDate,7),datestr(surgeryDate,11)];
            commaPosition = strfind(name,',');
            lastName = strtrim(name(1:commaPosition-1));
            firstName = strtrim(name(commaPosition+1:end));
            if isempty(mouseID), mouseID = 'Unknown'; end
            fileName = [app.SaveFilesFolder,'\Notes_Mouse_',mouseID,'_Date_',formattedDate,'_Name_',firstName,'_',lastName,'.txt'];
            addsurgerynotes(fileName);
        end

        % Value changed function: BregmaAPEditField, 
        % BregmaDVEditField, BregmaMLEditField, LambdaAPEditField, 
        % LambdaDVEditField, LambdaMLEditField, MidlineDVEditField_1, 
        % MidlineDVEditField_2, MidlineDVEditField_3, 
        % RelativeAPEditField_1, RelativeAPEditField_2, 
        % RelativeAPEditField_3, RelativeAPEditField_4, 
        % RelativeDVEditField_1, RelativeDVEditField_2, 
        % RelativeDVEditField_3, RelativeDVEditField_4, 
        % RelativeMLEditField_1, RelativeMLEditField_2, 
        % RelativeMLEditField_3, RelativeMLEditField_4
        function APEditFieldValueChanged(app, event)
            updatesites(app)
        end

        % Value changed function: LeftRightSwitch_1
        function LeftRightSwitch_1ValueChanged(app, event)
            app.RelativeMLEditField_1.Value = -app.RelativeMLEditField_1.Value;
            updatesites(app)
        end

        % Value changed function: LeftRightSwitch_2
        function LeftRightSwitch_2ValueChanged(app, event)
            app.MLEditField_11.Value = -app.MLEditField_11.Value;
            updatesites(app)
        end

        % Value changed function: LeftRightSwitch_3
        function LeftRightSwitch_3ValueChanged(app, event)
            app.MLEditField_13.Value = -app.MLEditField_13.Value;
            updatesites(app)
        end

        % Value changed function: LeftRightSwitch_4
        function LeftRightSwitch_4ValueChanged(app, event)
            app.MLEditField_15.Value = -app.MLEditField_15.Value;
            updatesites(app)
        end

        % Button pushed function: SaveLogButton
        function SaveLogButtonPushed(app, event)
            
            % Collect data from GUI
            
            out.name = app.NameDropDown.Value;
            out.surgeryDate = app.DateEditField.Value;
            out.mouseID = app.MouseIDEditField.Value;
            out.mouseWeight = app.WeightEditField.Value;
            out.mouseSex = app.SexDropDown.Value;
            out.mouseDOB = app.DOBEditField.Value;
            out.surgeryStartTime = app.StartTimeEditField.Value;
            out.surgeryStopTime = app.StopTimeEditField.Value;
            out.anesthesia = app.AnesthesiaDropDown.Value;
            out.surgeryType = app.SurgeryTypeDropDown.Value;
            out.ketorolac = app.KetorolacEditField.Value;
            out.description = app.DescriptionTextArea.Value;
            out.bregmaPosition = [app.BregmaAPEditField.Value, app.BregmaMLEditField.Value, app.BregmaDVEditField.Value];
            out.lambdaPosition = [app.LambdaAPEditField.Value, app.LambdaMLEditField.Value, app.LambdaDVEditField.Value];
            for iTabs = 1:4
                out.site(iTabs).Area = app.(['AreaDropDown_',num2str(iTabs)]).Value;
                APName1 = ['RelativeAPEditField_',num2str(iTabs)];
                APName2 = ['AbsoluteAPEditField_',num2str(iTabs)];
                MLName1 = ['RelativeMLEditField_',num2str(iTabs)];
                MLName2 = ['AbsoluteMLEditField_',num2str(iTabs)];
                DVName1 = ['RelativeDVEditField_',num2str(iTabs)];
                DVName2 = ['AbsoluteDVEditField_',num2str(iTabs)];
                MidlineName = ['MidlineDVEditField_',num2str(iTabs)];
                out.site(iTabs).midlineDV = app.(MidlineName).Value;
                out.site(iTabs).relativeAP = app.(APName1).Value;
                out.site(iTabs).absoluteAP = app.(APName2).Value;
                out.site(iTabs).relativeML = app.(MLName1).Value;
                out.site(iTabs).absoluteML = app.(MLName2).Value;
                out.site(iTabs).relativeDV = app.(DVName1).Value;
                out.site(iTabs).absoluteDV = app.(DVName2).Value;
                out.site(iTabs).virus = app.(['VirusEditField_',num2str(iTabs)]).Value;
                if contains(out.site(iTabs).virus,'name')
                    out.site(iTabs).virus = [];
                end
                out.site(iTabs).fiber = app.(['FiberCheckBox_',num2str(iTabs)]).Value;
                out.site(iTabs).lens = app.(['LensCheckBox_',num2str(iTabs)]).Value;
                out.site(iTabs).bilateral = app.(['BilateralSwitch_',num2str(iTabs)]).Value;
                out.site(iTabs).leftright = app.(['LeftRightSwitch_',num2str(iTabs)]).Value;
            end
            
            
            % Save MAT file and PDF file (local and Google Drive)
            formattedDate = [datestr(out.surgeryDate,5),datestr(out.surgeryDate,7),datestr(out.surgeryDate,11)];
            commaPosition = strfind(out.name,',');
            lastName = strtrim(out.name(1:commaPosition-1));
            firstName = strtrim(out.name(commaPosition+1:end));
            if isempty(out.mouseID) || contains(out.mouseID,'enter')
                warndlg('Enter a mouse ID before saving.');
                return
            end
            logfileName = ['SurgeryLog_Mouse_',out.mouseID,'_Date_',formattedDate,'_Name_',firstName,'_',lastName,'.mat'];
            MfileName = logfileName(1:end-2); % .m rather than .mat
            out1 = out;
            idx = [];
            for kk = 1:4
                if strcmp(out1.site(kk).Area,'None')
                    idx(end+1) = kk;
                end
            end
            out1.site(idx) = []; 
            matlab.io.saveVariablesToScript([app.SaveFilesFolder,'\',MfileName],'out1')
            save([app.SaveFilesFolder,'\',logfileName],'out');
%             PDFfileName = [app.SaveFilesFolder,'\',MfileName];
%             currentDir = pwd;
%             cd(app.SaveFilesFolder)
%             publish(PDFfileName,'format','pdf','outputDir',app.SaveFilesFolder);
%             cd(currentDir)
            
        end

        % Button pushed function: LookupButton
        function LookupButtonPushed(app, event)
            getlogfilename(app,app.SaveFilesFolder);
        end

        % Value changed function: MouseIDEditField
        function MouseIDEditFieldValueChanged(app, event)
            value = app.MouseIDEditField.Value;
            value(isspace(value)) = [];
            app.MouseIDEditField.Value = value;
        end

        % Value changed function: WeightEditField
        function WeightEditFieldValueChanged(app, event)
            value = app.WeightEditField.Value;
            ketorolac = value * (0.1/20); % volume in mL if dosage is 0.1 mL per 20 g mouse
            app.KetorolacEditField.Value = ketorolac;
        end

        % Value changed function: AreaDropDown_1
        function AreaDropDown_1ValueChanged(app, event)
            value = app.AreaDropDown_1.Value;
            if strncmp(value, 'Add', 3)
                getbraininfo(app,app.SaveFilesFolder)
            else
                updatebraininfo(app,false)
                updatesites(app)
            end
        end

        % Value changed function: AreaDropDown_2
        function AreaDropDown_2ValueChanged(app, event)
            value = app.AreaDropDown_2.Value;
            if strncmp(value, 'Add', 3)
                getbraininfo(app,app.SaveFilesFolder)
            else
                updatebraininfo(app,false)
                updatesites(app)
            end
        end

        % Value changed function: AreaDropDown_3
        function AreaDropDown_3ValueChanged(app, event)
            value = app.AreaDropDown_3.Value;
            if strncmp(value, 'Add', 3)
                getbraininfo(app,app.SaveFilesFolder)
            else
                updatebraininfo(app,false)
                updatesites(app)
            end
        end

        % Value changed function: AreaDropDown_4
        function AreaDropDown_4ValueChanged(app, event)
            value = app.AreaDropDown_4.Value;
            if strncmp(value, 'Add', 3)
                getbraininfo(app,app.SaveFilesFolder)
            else
                updatebraininfo(app,false)
                updatesites(app)
            end
        end

        % Value changed function: LandmarkSwitch_1, LandmarkSwitch_2, 
        % LandmarkSwitch_3, LandmarkSwitch_4
        function LandmarkSwitch_1ValueChanged(app, event)
            updatebraininfo(app,false)
            updatesites(app)            
        end

        % Close request function: RolemageLaboratoryUIFigure
        function RolemageLaboratoryUIFigureCloseRequest(app, event)
            
            app.RolemageLaboratoryUIFigure.Visible = 'off';
            delete(app)
            
        end

        % Button pushed function: ZoomButton
        function ZoomButtonPushed(app, event)
            zoomfigure(app)
        end

        % Value changed function: DOBEditField
        function DOBEditFieldValueChanged(app, event)
            value = app.DOBEditField.Value;
            try
                app.DOBEditField.Value = datestr(value,2);
            catch
                warndlg('Add a valid date in the form MM/DD/YY','DOB Warning','modal');
                app.DOBEditField.Value = 'MM/DD/YY';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RolemageLaboratoryUIFigure = uifigure;
            app.RolemageLaboratoryUIFigure.Name = 'Rolemage Laboratory';
            app.RolemageLaboratoryUIFigure.CloseRequestFcn = createCallbackFcn(app, @RolemageLaboratoryUIFigureCloseRequest, true);
            app.NameDropDownLabel = uilabel(app.RolemageLaboratoryUIFigure);
            app.NameDropDownLabel.Text = 'Name';
            app.NameDropDown = uidropdown(app.RolemageLaboratoryUIFigure);
            app.NameDropDown.Items = {'Ananth, Mala', 'Desai, Niraj', 'Jone, Alice', 'Parsey, Darian', 'Rajebhosale, Prithviraj ', 'Wilking, Jennifer', 'Zhong, Chongbo', 'Add / Delete'};
            app.NameDropDown.ValueChangedFcn = createCallbackFcn(app, @NameDropDownValueChanged, true);
            app.NameDropDown.Value = 'Ananth, Mala';
            app.MouseIDLabel = uilabel(app.RolemageLaboratoryUIFigure);
            app.MouseIDLabel.Text = 'Mouse ID';
            app.MouseIDEditField = uieditfield(app.RolemageLaboratoryUIFigure, 'text');
            app.MouseIDEditField.ValueChangedFcn = createCallbackFcn(app, @MouseIDEditFieldValueChanged, true);
            app.MouseIDEditField.Value = 'enter ID here';
            app.DateEditFieldLabel = uilabel(app.RolemageLaboratoryUIFigure);
            app.DateEditFieldLabel.Text = 'Date';
            app.DateEditField = uieditfield(app.RolemageLaboratoryUIFigure, 'text');
            app.DateEditField.Editable = 'off';
            app.DateEditField.Value = 'today''s date';
            app.SurgeryTypeDropDownLabel = uilabel(app.RolemageLaboratoryUIFigure);
            app.SurgeryTypeDropDownLabel.Text = 'Surgery Type';
            app.SurgeryTypeDropDown = uidropdown(app.RolemageLaboratoryUIFigure);
            app.SurgeryTypeDropDown.Items = {'Virus only', 'Light fiber', 'GRIN lens', 'GRIN baseplate'};
            app.SurgeryTypeDropDown.Value = 'Virus only';
            app.AnesthesiaDropDownLabel = uilabel(app.RolemageLaboratoryUIFigure);
            app.AnesthesiaDropDownLabel.Text = 'Anesthesia';
            app.AnesthesiaDropDown = uidropdown(app.RolemageLaboratoryUIFigure);
            app.AnesthesiaDropDown.Items = {'Isoflurane', 'Ketamine'};
            app.AnesthesiaDropDown.Value = 'Isoflurane';
            app.StartTimeEditFieldLabel = uilabel(app.RolemageLaboratoryUIFigure);
            app.StartTimeEditFieldLabel.Text = 'Start Time';
            app.StartTimeEditField = uieditfield(app.RolemageLaboratoryUIFigure, 'text');
            app.StartTimeEditField.Value = '11:00';
            app.StopTimeEditFieldLabel = uilabel(app.RolemageLaboratoryUIFigure);
            app.StopTimeEditFieldLabel.Text = 'Stop Time';
            app.StopTimeEditField = uieditfield(app.RolemageLaboratoryUIFigure, 'text');
            app.StopTimeEditField.Value = '13:00';
            app.APEditFieldLabel = uilabel(app.RolemageLaboratoryUIFigure);
            app.APEditFieldLabel.Text = 'AP';
            app.BregmaAPEditField = uieditfield(app.RolemageLaboratoryUIFigure, 'numeric');
            app.BregmaAPEditField.Limits = [-20 20];
            app.BregmaAPEditField.ValueDisplayFormat = '%1.4f';
            app.BregmaAPEditField.ValueChangedFcn = createCallbackFcn(app, @APEditFieldValueChanged, true);
            app.MLEditFieldLabel = uilabel(app.RolemageLaboratoryUIFigure);
            app.MLEditFieldLabel.Text = 'ML';
            app.BregmaMLEditField = uieditfield(app.RolemageLaboratoryUIFigure, 'numeric');
            app.BregmaMLEditField.Limits = [-20 20];
            app.BregmaMLEditField.ValueDisplayFormat = '%1.4f';
            app.BregmaMLEditField.ValueChangedFcn = createCallbackFcn(app, @APEditFieldValueChanged, true);
            app.DVEditFieldLabel = uilabel(app.RolemageLaboratoryUIFigure);
            app.DVEditFieldLabel.Text = 'DV';
            app.BregmaDVEditField = uieditfield(app.RolemageLaboratoryUIFigure, 'numeric');
            app.BregmaDVEditField.Limits = [-20 20];
            app.BregmaDVEditField.ValueDisplayFormat = '%1.4f';
            app.BregmaDVEditField.ValueChangedFcn = createCallbackFcn(app, @APEditFieldValueChanged, true);
            app.DescriptionTextAreaLabel = uilabel(app.RolemageLaboratoryUIFigure);
            app.DescriptionTextAreaLabel.Text = 'Description';
            app.DescriptionTextArea = uitextarea(app.RolemageLaboratoryUIFigure);
            app.BregmaLabel = uilabel(app.RolemageLaboratoryUIFigure);
            app.BregmaLabel.Text = 'Bregma';
            app.LambdaLabel = uilabel(app.RolemageLaboratoryUIFigure);
            app.LambdaLabel.Text = 'Lambda';
            app.MLEditField_2Label = uilabel(app.RolemageLaboratoryUIFigure);
            app.MLEditField_2Label.Text = 'ML';
            app.LambdaMLEditField = uieditfield(app.RolemageLaboratoryUIFigure, 'numeric');
            app.LambdaMLEditField.Limits = [-20 20];
            app.LambdaMLEditField.ValueDisplayFormat = '%1.4f';
            app.LambdaMLEditField.ValueChangedFcn = createCallbackFcn(app, @APEditFieldValueChanged, true);
            app.DVEditField_2Label = uilabel(app.RolemageLaboratoryUIFigure);
            app.DVEditField_2Label.Text = 'DV';
            app.LambdaDVEditField = uieditfield(app.RolemageLaboratoryUIFigure, 'numeric');
            app.LambdaDVEditField.Limits = [-20 20];
            app.LambdaDVEditField.ValueDisplayFormat = '%1.4f';
            app.LambdaDVEditField.ValueChangedFcn = createCallbackFcn(app, @APEditFieldValueChanged, true);
            app.SurgeryLogLabel = uilabel(app.RolemageLaboratoryUIFigure);
            app.SurgeryLogLabel.Text = 'Surgery Log';
            app.TabGroup = uitabgroup(app.RolemageLaboratoryUIFigure);
            app.Site1Tab = uitab(app.TabGroup);
            app.Site1Tab.Title = 'Site 1';
            app.AreaDropDownLabel = uilabel(app.Site1Tab);
            app.AreaDropDownLabel.Text = 'Area';
            app.AreaDropDown_1 = uidropdown(app.Site1Tab);
            app.AreaDropDown_1.Items = {'None', 'NBM', 'BLA', 'vHIPP', 'PFC', 'Add / Edit'};
            app.AreaDropDown_1.ValueChangedFcn = createCallbackFcn(app, @AreaDropDown_1ValueChanged, true);
            app.AreaDropDown_1.Value = 'None';
            app.MidlineDVEditFieldLabel = uilabel(app.Site1Tab);
            app.MidlineDVEditFieldLabel.Text = 'Midline DV';
            app.MidlineDVEditField_1 = uieditfield(app.Site1Tab, 'numeric');
            app.MidlineDVEditField_1.Limits = [-20 20];
            app.MidlineDVEditField_1.ValueDisplayFormat = '%1.4f';
            app.MidlineDVEditField_1.ValueChangedFcn = createCallbackFcn(app, @APEditFieldValueChanged, true);
            app.VirusEditField_4Label = uilabel(app.Site1Tab);
            app.VirusEditField_4Label.Text = 'Virus';
            app.VirusEditField_1 = uieditfield(app.Site1Tab, 'text');
            app.VirusEditField_1.Value = 'name & conc';
            app.FiberCheckBox_1 = uicheckbox(app.Site1Tab);
            app.FiberCheckBox_1.Text = 'Fiber';
            app.LensCheckBox_1 = uicheckbox(app.Site1Tab);
            app.LensCheckBox_1.Text = 'Lens';
            app.APEditField_3Label = uilabel(app.Site1Tab);
            app.APEditField_3Label.Text = 'AP';
            app.RelativeAPEditField_1 = uieditfield(app.Site1Tab, 'numeric');
            app.RelativeAPEditField_1.ValueDisplayFormat = '%1.4f';
            app.RelativeAPEditField_1.ValueChangedFcn = createCallbackFcn(app, @APEditFieldValueChanged, true);
            app.MLEditField_3Label = uilabel(app.Site1Tab);
            app.MLEditField_3Label.Text = 'ML';
            app.RelativeMLEditField_1 = uieditfield(app.Site1Tab, 'numeric');
            app.RelativeMLEditField_1.ValueDisplayFormat = '%1.4f';
            app.RelativeMLEditField_1.ValueChangedFcn = createCallbackFcn(app, @APEditFieldValueChanged, true);
            app.DVEditField_3Label = uilabel(app.Site1Tab);
            app.DVEditField_3Label.Text = 'DV';
            app.RelativeDVEditField_1 = uieditfield(app.Site1Tab, 'numeric');
            app.RelativeDVEditField_1.Limits = [-20 20];
            app.RelativeDVEditField_1.ValueDisplayFormat = '%1.4f';
            app.RelativeDVEditField_1.ValueChangedFcn = createCallbackFcn(app, @APEditFieldValueChanged, true);
            app.APEditField_4Label = uilabel(app.Site1Tab);
            app.APEditField_4Label.Text = 'AP';
            app.AbsoluteAPEditField_1 = uieditfield(app.Site1Tab, 'numeric');
            app.AbsoluteAPEditField_1.ValueDisplayFormat = '%1.4f';
            app.AbsoluteAPEditField_1.Editable = 'off';
            app.MLEditField_4Label = uilabel(app.Site1Tab);
            app.MLEditField_4Label.Text = 'ML';
            app.AbsoluteMLEditField_1 = uieditfield(app.Site1Tab, 'numeric');
            app.AbsoluteMLEditField_1.ValueDisplayFormat = '%1.4f';
            app.AbsoluteMLEditField_1.Editable = 'off';
            app.DVEditField_4Label = uilabel(app.Site1Tab);
            app.DVEditField_4Label.Text = 'DV';
            app.AbsoluteDVEditField_1 = uieditfield(app.Site1Tab, 'numeric');
            app.AbsoluteDVEditField_1.ValueDisplayFormat = '%1.4f';
            app.AbsoluteDVEditField_1.Editable = 'off';
            app.RelativeLabel_1 = uilabel(app.Site1Tab);
            app.RelativeLabel_1.Text = 'Relative';
            app.AbsoluteLabel_1 = uilabel(app.Site1Tab);
            app.AbsoluteLabel_1.Text = 'Absolute';
            app.BilateralSwitch_1 = uiswitch(app.Site1Tab, 'slider');
            app.BilateralSwitch_1.Items = {'Bilateral', 'Unilateral'};
            app.BilateralSwitch_1.Value = 'Bilateral';
            app.LeftRightSwitch_1 = uiswitch(app.Site1Tab, 'slider');
            app.LeftRightSwitch_1.Items = {'Right', 'Left'};
            app.LeftRightSwitch_1.ValueChangedFcn = createCallbackFcn(app, @LeftRightSwitch_1ValueChanged, true);
            app.LeftRightSwitch_1.Value = 'Right';
            app.LandmarkSwitch_1 = uiswitch(app.Site1Tab, 'slider');
            app.LandmarkSwitch_1.Items = {'Bregma', 'Lambda'};
            app.LandmarkSwitch_1.ValueChangedFcn = createCallbackFcn(app, @LandmarkSwitch_1ValueChanged, true);
            app.LandmarkSwitch_1.Value = 'Bregma';
            app.Site2Tab = uitab(app.TabGroup);
            app.Site2Tab.Title = 'Site 2';
            app.FiberCheckBox_2 = uicheckbox(app.Site2Tab);
            app.FiberCheckBox_2.Text = 'Fiber';
            app.LensCheckBox_2 = uicheckbox(app.Site2Tab);
            app.LensCheckBox_2.Text = 'Lens';
            app.RelativeLabel_2 = uilabel(app.Site2Tab);
            app.RelativeLabel_2.Text = 'Relative';
            app.AbsoluteLabel_2 = uilabel(app.Site2Tab);
            app.AbsoluteLabel_2.Text = 'Absolute';
            app.BilateralSwitch_2 = uiswitch(app.Site2Tab, 'slider');
            app.BilateralSwitch_2.Items = {'Bilateral', 'Unilateral'};
            app.BilateralSwitch_2.Value = 'Bilateral';
            app.LeftRightSwitch_2 = uiswitch(app.Site2Tab, 'slider');
            app.LeftRightSwitch_2.Items = {'Right', 'Left'};
            app.LeftRightSwitch_2.ValueChangedFcn = createCallbackFcn(app, @LeftRightSwitch_2ValueChanged, true);
            app.LeftRightSwitch_2.Value = 'Right';
            app.LandmarkSwitch_2 = uiswitch(app.Site2Tab, 'slider');
            app.LandmarkSwitch_2.Items = {'Bregma', 'Lambda'};
            app.LandmarkSwitch_2.ValueChangedFcn = createCallbackFcn(app, @LandmarkSwitch_1ValueChanged, true);
            app.LandmarkSwitch_2.Value = 'Bregma';
            app.AreaDropDown_5Label = uilabel(app.Site2Tab);
            app.AreaDropDown_5Label.Text = 'Area';
            app.AreaDropDown_2 = uidropdown(app.Site2Tab);
            app.AreaDropDown_2.Items = {'None', 'NBM', 'BLA', 'vHIPP', 'PFC', 'Add / Edit'};
            app.AreaDropDown_2.ValueChangedFcn = createCallbackFcn(app, @AreaDropDown_2ValueChanged, true);
            app.AreaDropDown_2.Value = 'None';
            app.MidlineDVEditField_8Label = uilabel(app.Site2Tab);
            app.MidlineDVEditField_8Label.Text = 'Midline DV';
            app.MidlineDVEditField_2 = uieditfield(app.Site2Tab, 'numeric');
            app.MidlineDVEditField_2.Limits = [-20 20];
            app.MidlineDVEditField_2.ValueDisplayFormat = '%1.4f';
            app.MidlineDVEditField_2.ValueChangedFcn = createCallbackFcn(app, @APEditFieldValueChanged, true);
            app.VirusEditField_8Label = uilabel(app.Site2Tab);
            app.VirusEditField_8Label.Text = 'Virus';
            app.VirusEditField_2 = uieditfield(app.Site2Tab, 'text');
            app.VirusEditField_2.Value = 'name & conc';
            app.APEditField_17Label_3 = uilabel(app.Site2Tab);
            app.APEditField_17Label_3.Text = 'AP';
            app.RelativeAPEditField_2 = uieditfield(app.Site2Tab, 'numeric');
            app.RelativeAPEditField_2.ValueDisplayFormat = '%1.4f';
            app.RelativeAPEditField_2.ValueChangedFcn = createCallbackFcn(app, @APEditFieldValueChanged, true);
            app.MLEditField_17Label_3 = uilabel(app.Site2Tab);
            app.MLEditField_17Label_3.Text = 'ML';
            app.RelativeMLEditField_2 = uieditfield(app.Site2Tab, 'numeric');
            app.RelativeMLEditField_2.ValueDisplayFormat = '%1.4f';
            app.RelativeMLEditField_2.ValueChangedFcn = createCallbackFcn(app, @APEditFieldValueChanged, true);
            app.DVEditField_17Label_3 = uilabel(app.Site2Tab);
            app.DVEditField_17Label_3.Text = 'DV';
            app.RelativeDVEditField_2 = uieditfield(app.Site2Tab, 'numeric');
            app.RelativeDVEditField_2.Limits = [-20 20];
            app.RelativeDVEditField_2.ValueDisplayFormat = '%1.4f';
            app.RelativeDVEditField_2.ValueChangedFcn = createCallbackFcn(app, @APEditFieldValueChanged, true);
            app.APEditField_18Label_3 = uilabel(app.Site2Tab);
            app.APEditField_18Label_3.Text = 'AP';
            app.AbsoluteAPEditField_2 = uieditfield(app.Site2Tab, 'numeric');
            app.AbsoluteAPEditField_2.ValueDisplayFormat = '%1.4f';
            app.AbsoluteAPEditField_2.Editable = 'off';
            app.MLEditField_18Label_3 = uilabel(app.Site2Tab);
            app.MLEditField_18Label_3.Text = 'ML';
            app.AbsoluteMLEditField_2 = uieditfield(app.Site2Tab, 'numeric');
            app.AbsoluteMLEditField_2.ValueDisplayFormat = '%1.4f';
            app.AbsoluteMLEditField_2.Editable = 'off';
            app.DVEditField_18Label_3 = uilabel(app.Site2Tab);
            app.DVEditField_18Label_3.Text = 'DV';
            app.AbsoluteDVEditField_2 = uieditfield(app.Site2Tab, 'numeric');
            app.AbsoluteDVEditField_2.ValueDisplayFormat = '%1.4f';
            app.AbsoluteDVEditField_2.Editable = 'off';
            app.Site3Tab = uitab(app.TabGroup);
            app.Site3Tab.Title = 'Site 3';
            app.FiberCheckBox_3 = uicheckbox(app.Site3Tab);
            app.FiberCheckBox_3.Text = 'Fiber';
            app.LensCheckBox_3 = uicheckbox(app.Site3Tab);
            app.LensCheckBox_3.Text = 'Lens';
            app.RelativeLabel_3 = uilabel(app.Site3Tab);
            app.RelativeLabel_3.Text = 'Relative';
            app.AbsoluteLabel_3 = uilabel(app.Site3Tab);
            app.AbsoluteLabel_3.Text = 'Absolute';
            app.BilateralSwitch_3 = uiswitch(app.Site3Tab, 'slider');
            app.BilateralSwitch_3.Items = {'Bilateral', 'Unilateral'};
            app.BilateralSwitch_3.Value = 'Bilateral';
            app.LeftRightSwitch_3 = uiswitch(app.Site3Tab, 'slider');
            app.LeftRightSwitch_3.Items = {'Right', 'Left'};
            app.LeftRightSwitch_3.ValueChangedFcn = createCallbackFcn(app, @LeftRightSwitch_3ValueChanged, true);
            app.LeftRightSwitch_3.Value = 'Right';
            app.LandmarkSwitch_3 = uiswitch(app.Site3Tab, 'slider');
            app.LandmarkSwitch_3.Items = {'Bregma', 'Lambda'};
            app.LandmarkSwitch_3.ValueChangedFcn = createCallbackFcn(app, @LandmarkSwitch_1ValueChanged, true);
            app.LandmarkSwitch_3.Value = 'Bregma';
            app.AreaDropDown_6Label = uilabel(app.Site3Tab);
            app.AreaDropDown_6Label.Text = 'Area';
            app.AreaDropDown_3 = uidropdown(app.Site3Tab);
            app.AreaDropDown_3.Items = {'None', 'NBM', 'BLA', 'vHIPP', 'PFC', 'Add / Edit'};
            app.AreaDropDown_3.ValueChangedFcn = createCallbackFcn(app, @AreaDropDown_3ValueChanged, true);
            app.AreaDropDown_3.Value = 'None';
            app.MidlineDVEditField_9Label = uilabel(app.Site3Tab);
            app.MidlineDVEditField_9Label.Text = 'Midline DV';
            app.MidlineDVEditField_3 = uieditfield(app.Site3Tab, 'numeric');
            app.MidlineDVEditField_3.Limits = [-20 20];
            app.MidlineDVEditField_3.ValueDisplayFormat = '%1.4f';
            app.MidlineDVEditField_3.ValueChangedFcn = createCallbackFcn(app, @APEditFieldValueChanged, true);
            app.VirusEditField_9Label = uilabel(app.Site3Tab);
            app.VirusEditField_9Label.Text = 'Virus';
            app.VirusEditField_3 = uieditfield(app.Site3Tab, 'text');
            app.VirusEditField_3.Value = 'name & conc';
            app.APEditField_19Label = uilabel(app.Site3Tab);
            app.APEditField_19Label.Text = 'AP';
            app.RelativeAPEditField_3 = uieditfield(app.Site3Tab, 'numeric');
            app.RelativeAPEditField_3.ValueDisplayFormat = '%1.4f';
            app.RelativeAPEditField_3.ValueChangedFcn = createCallbackFcn(app, @APEditFieldValueChanged, true);
            app.MLEditField_19Label = uilabel(app.Site3Tab);
            app.MLEditField_19Label.Text = 'ML';
            app.RelativeMLEditField_3 = uieditfield(app.Site3Tab, 'numeric');
            app.RelativeMLEditField_3.ValueDisplayFormat = '%1.4f';
            app.RelativeMLEditField_3.ValueChangedFcn = createCallbackFcn(app, @APEditFieldValueChanged, true);
            app.DVEditField_19Label = uilabel(app.Site3Tab);
            app.DVEditField_19Label.Text = 'DV';
            app.RelativeDVEditField_3 = uieditfield(app.Site3Tab, 'numeric');
            app.RelativeDVEditField_3.Limits = [-20 20];
            app.RelativeDVEditField_3.ValueDisplayFormat = '%1.4f';
            app.RelativeDVEditField_3.ValueChangedFcn = createCallbackFcn(app, @APEditFieldValueChanged, true);
            app.APEditField_20Label = uilabel(app.Site3Tab);
            app.APEditField_20Label.Text = 'AP';
            app.AbsoluteAPEditField_3 = uieditfield(app.Site3Tab, 'numeric');
            app.AbsoluteAPEditField_3.ValueDisplayFormat = '%1.4f';
            app.AbsoluteAPEditField_3.Editable = 'off';
            app.MLEditField_20Label = uilabel(app.Site3Tab);
            app.MLEditField_20Label.Text = 'ML';
            app.AbsoluteMLEditField_3 = uieditfield(app.Site3Tab, 'numeric');
            app.AbsoluteMLEditField_3.ValueDisplayFormat = '%1.4f';
            app.AbsoluteMLEditField_3.Editable = 'off';
            app.DVEditField_20Label = uilabel(app.Site3Tab);
            app.DVEditField_20Label.Text = 'DV';
            app.AbsoluteDVEditField_3 = uieditfield(app.Site3Tab, 'numeric');
            app.AbsoluteDVEditField_3.ValueDisplayFormat = '%1.4f';
            app.AbsoluteDVEditField_3.Editable = 'off';
            app.Site4Tab = uitab(app.TabGroup);
            app.Site4Tab.Title = 'Site 4';
            app.FiberCheckBox_4 = uicheckbox(app.Site4Tab);
            app.FiberCheckBox_4.Text = 'Fiber';
            app.LensCheckBox_4 = uicheckbox(app.Site4Tab);
            app.LensCheckBox_4.Text = 'Lens';
            app.RelativeLabel_4 = uilabel(app.Site4Tab);
            app.RelativeLabel_4.Text = 'Relative';
            app.AbsoluteLabel_4 = uilabel(app.Site4Tab);
            app.AbsoluteLabel_4.Text = 'Absolute';
            app.BilateralSwitch_4 = uiswitch(app.Site4Tab, 'slider');
            app.BilateralSwitch_4.Items = {'Bilateral', 'Unilateral'};
            app.BilateralSwitch_4.Value = 'Bilateral';
            app.LeftRightSwitch_4 = uiswitch(app.Site4Tab, 'slider');
            app.LeftRightSwitch_4.Items = {'Right', 'Left'};
            app.LeftRightSwitch_4.ValueChangedFcn = createCallbackFcn(app, @LeftRightSwitch_4ValueChanged, true);
            app.LeftRightSwitch_4.Value = 'Right';
            app.LandmarkSwitch_4 = uiswitch(app.Site4Tab, 'slider');
            app.LandmarkSwitch_4.Items = {'Bregma', 'Lambda'};
            app.LandmarkSwitch_4.ValueChangedFcn = createCallbackFcn(app, @LandmarkSwitch_1ValueChanged, true);
            app.LandmarkSwitch_4.Value = 'Bregma';
            app.AreaDropDown_7Label = uilabel(app.Site4Tab);
            app.AreaDropDown_7Label.Text = 'Area';
            app.AreaDropDown_4 = uidropdown(app.Site4Tab);
            app.AreaDropDown_4.Items = {'None', 'NBM', 'BLA', 'vHIPP', 'PFC', 'Add / Edit'};
            app.AreaDropDown_4.ValueChangedFcn = createCallbackFcn(app, @AreaDropDown_4ValueChanged, true);
            app.AreaDropDown_4.Value = 'None';
            app.MidlineDVEditField_10Label = uilabel(app.Site4Tab);
            app.MidlineDVEditField_10Label.Text = 'Midline DV';
            app.MidlineDVEditField_4 = uieditfield(app.Site4Tab, 'numeric');
            app.MidlineDVEditField_4.Limits = [-20 20];
            app.MidlineDVEditField_4.ValueDisplayFormat = '%1.4f';
            app.VirusEditField_10Label = uilabel(app.Site4Tab);
            app.VirusEditField_10Label.Text = 'Virus';
            app.VirusEditField_4 = uieditfield(app.Site4Tab, 'text');
            app.VirusEditField_4.Value = 'name & conc';
            app.APEditField_21Label = uilabel(app.Site4Tab);
            app.APEditField_21Label.Text = 'AP';
            app.RelativeAPEditField_4 = uieditfield(app.Site4Tab, 'numeric');
            app.RelativeAPEditField_4.ValueDisplayFormat = '%1.4f';
            app.RelativeAPEditField_4.ValueChangedFcn = createCallbackFcn(app, @APEditFieldValueChanged, true);
            app.MLEditField_21Label = uilabel(app.Site4Tab);
            app.MLEditField_21Label.Text = 'ML';
            app.RelativeMLEditField_4 = uieditfield(app.Site4Tab, 'numeric');
            app.RelativeMLEditField_4.ValueDisplayFormat = '%1.4f';
            app.RelativeMLEditField_4.ValueChangedFcn = createCallbackFcn(app, @APEditFieldValueChanged, true);
            app.DVEditField_21Label = uilabel(app.Site4Tab);
            app.DVEditField_21Label.Text = 'DV';
            app.RelativeDVEditField_4 = uieditfield(app.Site4Tab, 'numeric');
            app.RelativeDVEditField_4.Limits = [-20 20];
            app.RelativeDVEditField_4.ValueDisplayFormat = '%1.4f';
            app.RelativeDVEditField_4.ValueChangedFcn = createCallbackFcn(app, @APEditFieldValueChanged, true);
            app.APEditField_22Label = uilabel(app.Site4Tab);
            app.APEditField_22Label.Text = 'AP';
            app.AbsoluteAPEditField_4 = uieditfield(app.Site4Tab, 'numeric');
            app.AbsoluteAPEditField_4.ValueDisplayFormat = '%1.4f';
            app.AbsoluteAPEditField_4.Editable = 'off';
            app.MLEditField_22Label = uilabel(app.Site4Tab);
            app.MLEditField_22Label.Text = 'ML';
            app.AbsoluteMLEditField_4 = uieditfield(app.Site4Tab, 'numeric');
            app.AbsoluteMLEditField_4.ValueDisplayFormat = '%1.4f';
            app.AbsoluteMLEditField_4.Editable = 'off';
            app.DVEditField_22Label = uilabel(app.Site4Tab);
            app.DVEditField_22Label.Text = 'DV';
            app.AbsoluteDVEditField_4 = uieditfield(app.Site4Tab, 'numeric');
            app.AbsoluteDVEditField_4.ValueDisplayFormat = '%1.4f';
            app.AbsoluteDVEditField_4.Editable = 'off';
            app.SaveLogButton = uibutton(app.RolemageLaboratoryUIFigure, 'push');
            app.SaveLogButton.ButtonPushedFcn = createCallbackFcn(app, @SaveLogButtonPushed, true);
            app.SaveLogButton.Text = 'Save Log';
            app.SexDropDown = uidropdown(app.RolemageLaboratoryUIFigure);
            app.SexDropDown.Items = {'F', 'M'};
            app.SexDropDown.Value = 'F';
            app.DOBEditFieldLabel = uilabel(app.RolemageLaboratoryUIFigure);
            app.DOBEditFieldLabel.Text = 'DOB';
            app.DOBEditField = uieditfield(app.RolemageLaboratoryUIFigure, 'text');
            app.DOBEditField.ValueChangedFcn = createCallbackFcn(app, @DOBEditFieldValueChanged, true);
            app.DOBEditField.Value = 'MM/DD/YY';
            app.gEditFieldLabel = uilabel(app.RolemageLaboratoryUIFigure);
            app.gEditFieldLabel.Text = 'g';
            app.WeightEditField = uieditfield(app.RolemageLaboratoryUIFigure, 'numeric');
            app.WeightEditField.Limits = [0 50];
            app.WeightEditField.ValueDisplayFormat = '%2.1f';
            app.WeightEditField.ValueChangedFcn = createCallbackFcn(app, @WeightEditFieldValueChanged, true);
            app.WeightEditField.Value = 20;
            app.AddNotesButton = uibutton(app.RolemageLaboratoryUIFigure, 'push');
            app.AddNotesButton.ButtonPushedFcn = createCallbackFcn(app, @AddNotesButtonPushed, true);
            app.AddNotesButton.Text = 'Add Notes';
            app.LookupButton = uibutton(app.RolemageLaboratoryUIFigure, 'push');
            app.LookupButton.ButtonPushedFcn = createCallbackFcn(app, @LookupButtonPushed, true);
            app.LookupButton.Text = 'Lookup';
            app.KetorolacLabel = uilabel(app.RolemageLaboratoryUIFigure);
            app.KetorolacLabel.Text = 'Ketorolac';
            app.KetorolacEditField = uieditfield(app.RolemageLaboratoryUIFigure, 'numeric');
            app.KetorolacEditField.Limits = [0 1];
            app.KetorolacEditField.ValueDisplayFormat = '%1.3f';
            app.KetorolacEditField.Editable = 'off';
            app.KetorolacEditField.Value = 0.1;
            app.mL01mL20gLabel = uilabel(app.RolemageLaboratoryUIFigure);
            app.mL01mL20gLabel.Text = 'mL (0.1 mL/20 g)';
            app.APEditField_2Label = uilabel(app.RolemageLaboratoryUIFigure);
            app.APEditField_2Label.Text = 'AP';
            app.LambdaAPEditField = uieditfield(app.RolemageLaboratoryUIFigure, 'numeric');
            app.LambdaAPEditField.Limits = [-20 20];
            app.LambdaAPEditField.ValueDisplayFormat = '%1.4f';
            app.LambdaAPEditField.ValueChangedFcn = createCallbackFcn(app, @APEditFieldValueChanged, true);
            app.ZoomButton = uibutton(app.RolemageLaboratoryUIFigure, 'push');
            app.ZoomButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomButtonPushed, true);
            app.ZoomButton.Icon = 'zoom-in.png';
            app.ZoomButton.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = surgerylog

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RolemageLaboratoryUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RolemageLaboratoryUIFigure)
        end
    end
end