classdef usecorticalsurfaces < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        UseCorticalSurfacePositionsLabel  matlab.ui.control.Label
        Surface1EditFieldLabel  matlab.ui.control.Label
        Surface1EditField       matlab.ui.control.NumericEditField
        Surface2EditFieldLabel  matlab.ui.control.Label
        Surface2EditField       matlab.ui.control.NumericEditField
        Target2EditFieldLabel   matlab.ui.control.Label
        Target2EditField        matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        CallingApp % zoomfigure app
        Delta % Target_1 - Surface_1
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, arg1)
            
            app.CallingApp = arg1;
            
        end

        % Value changed function: Surface1EditField, Surface2EditField
        function Surface1EditFieldValueChanged(app, event)
            app.Delta = app.CallingApp.Absolute_DV.Value - app.Surface1EditField.Value;
            app.Target2EditField.Value = app.Surface2EditField.Value + app.Delta;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UseCorticalSurfacePositionsLabel = uilabel(app.UIFigure);
            app.UseCorticalSurfacePositionsLabel.Text = 'Use Cortical Surface Positions';
            app.Surface1EditFieldLabel = uilabel(app.UIFigure);
            app.Surface1EditFieldLabel.Text = 'Surface 1';
            app.Surface1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Surface1EditField.ValueDisplayFormat = '%1.4f';
            app.Surface1EditField.ValueChangedFcn = createCallbackFcn(app, @Surface1EditFieldValueChanged, true);
            app.Surface2EditFieldLabel = uilabel(app.UIFigure);
            app.Surface2EditFieldLabel.Text = 'Surface 2';
            app.Surface2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Surface2EditField.ValueDisplayFormat = '%1.4f';
            app.Surface2EditField.ValueChangedFcn = createCallbackFcn(app, @Surface1EditFieldValueChanged, true);
            app.Target2EditFieldLabel = uilabel(app.UIFigure);
            app.Target2EditFieldLabel.Text = 'Target 2';
            app.Target2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Target2EditField.ValueDisplayFormat = '%1.4f';
            app.Target2EditField.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = usecorticalsurfaces(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end