classdef zoomfigure < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ZoomUIFigure           matlab.ui.Figure
        ZoomLabel              matlab.ui.control.Label
        BregmaLabel            matlab.ui.control.Label
        LambdaLabel            matlab.ui.control.Label
        MidlineLabel           matlab.ui.control.Label
        AbsoluteLabel          matlab.ui.control.Label
        APLabel                matlab.ui.control.Label
        MLLabel                matlab.ui.control.Label
        DVLabel                matlab.ui.control.Label
        SiteDropDownLabel      matlab.ui.control.Label
        SiteDropDown           matlab.ui.control.DropDown
        Bregma_AP              matlab.ui.control.NumericEditField
        Bregma_ML              matlab.ui.control.NumericEditField
        Bregma_DV              matlab.ui.control.NumericEditField
        Lambda_AP              matlab.ui.control.NumericEditField
        Lambda_ML              matlab.ui.control.NumericEditField
        Lambda_DV              matlab.ui.control.NumericEditField
        Midline_DV             matlab.ui.control.NumericEditField
        Absolute_AP            matlab.ui.control.NumericEditField
        Absolute_ML            matlab.ui.control.NumericEditField
        Absolute_DV            matlab.ui.control.NumericEditField
        BilateralFibersButton  matlab.ui.control.Button
    end


    properties (Access = public)
        CallingApp % Description
    end

    methods (Access = private)
    
        function populatezoomfigure(app)
            
            siteNo = app.SiteDropDown.Value;
            app.Bregma_AP.Value = app.CallingApp.BregmaAPEditField.Value;
            app.Bregma_ML.Value = app.CallingApp.BregmaMLEditField.Value;
            app.Bregma_DV.Value = app.CallingApp.BregmaDVEditField.Value;
            app.Lambda_AP.Value = app.CallingApp.LambdaAPEditField.Value;
            app.Lambda_ML.Value = app.CallingApp.LambdaMLEditField.Value;
            app.Lambda_DV.Value = app.CallingApp.LambdaDVEditField.Value;
            fStr = ['MidlineDVEditField_',siteNo];
            app.Midline_DV.Value = app.CallingApp.(fStr).Value;
            fStr = ['AbsoluteAPEditField_',siteNo];
            app.Absolute_AP.Value = app.CallingApp.(fStr).Value;
            fStr = ['AbsoluteMLEditField_',siteNo];
            app.Absolute_ML.Value = app.CallingApp.(fStr).Value;
            fStr = ['AbsoluteDVEditField_',siteNo];
            app.Absolute_DV.Value = app.CallingApp.(fStr).Value;

        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, arg1)
            
            app.CallingApp = arg1;
            populatezoomfigure(app)
            
            
        end

        % Value changed function: SiteDropDown
        function SiteDropDownValueChanged(app, event)
            
            populatezoomfigure(app)
            
        end

        % Value changed function: Bregma_AP, Bregma_DV, Bregma_ML, 
        % Lambda_AP, Lambda_DV, Lambda_ML, Midline_DV
        function Bregma_APValueChanged(app, event)
                        
            out.siteNo = app.SiteDropDown.Value;
            out.BregmaAP = app.Bregma_AP.Value;
            out.BregmaML = app.Bregma_ML.Value;
            out.BregmaDV = app.Bregma_DV.Value;
            out.LambdaAP = app.Lambda_AP.Value;
            out.LambdaML = app.Lambda_ML.Value;
            out.LambdaDV = app.Lambda_DV.Value;
            out.MidlineDV = app.Midline_DV.Value;
            updatefromzoom(app.CallingApp,out)
            populatezoomfigure(app)
            
        end

        % Button pushed function: BilateralFibersButton
        function BilateralFibersButtonPushed(app, event)
            usecorticalsurfaces(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ZoomUIFigure = uifigure;
            app.ZoomUIFigure.Name = 'UI Figure';
            app.ZoomLabel = uilabel(app.ZoomUIFigure);
            app.ZoomLabel.Text = 'Zoom';
            app.BregmaLabel = uilabel(app.ZoomUIFigure);
            app.BregmaLabel.Text = 'Bregma';
            app.LambdaLabel = uilabel(app.ZoomUIFigure);
            app.LambdaLabel.Text = 'Lambda';
            app.MidlineLabel = uilabel(app.ZoomUIFigure);
            app.MidlineLabel.Text = 'Midline';
            app.AbsoluteLabel = uilabel(app.ZoomUIFigure);
            app.AbsoluteLabel.Text = 'Absolute';
            app.APLabel = uilabel(app.ZoomUIFigure);
            app.APLabel.Text = 'AP';
            app.MLLabel = uilabel(app.ZoomUIFigure);
            app.MLLabel.Text = 'ML';
            app.DVLabel = uilabel(app.ZoomUIFigure);
            app.DVLabel.Text = 'DV';
            app.SiteDropDownLabel = uilabel(app.ZoomUIFigure);
            app.SiteDropDownLabel.Text = 'Site';
            app.SiteDropDown = uidropdown(app.ZoomUIFigure);
            app.SiteDropDown.Items = {'1', '2', '3', '4'};
            app.SiteDropDown.ValueChangedFcn = createCallbackFcn(app, @SiteDropDownValueChanged, true);
            app.SiteDropDown.Value = '1';
            app.Bregma_AP = uieditfield(app.ZoomUIFigure, 'numeric');
            app.Bregma_AP.Limits = [0 10];
            app.Bregma_AP.ValueDisplayFormat = '%1.4f';
            app.Bregma_AP.ValueChangedFcn = createCallbackFcn(app, @Bregma_APValueChanged, true);
            app.Bregma_AP.Value = 2.1314;
            app.Bregma_ML = uieditfield(app.ZoomUIFigure, 'numeric');
            app.Bregma_ML.Limits = [0 10];
            app.Bregma_ML.ValueDisplayFormat = '%1.4f';
            app.Bregma_ML.ValueChangedFcn = createCallbackFcn(app, @Bregma_APValueChanged, true);
            app.Bregma_ML.Value = 2.1314;
            app.Bregma_DV = uieditfield(app.ZoomUIFigure, 'numeric');
            app.Bregma_DV.Limits = [0 10];
            app.Bregma_DV.ValueDisplayFormat = '%1.4f';
            app.Bregma_DV.ValueChangedFcn = createCallbackFcn(app, @Bregma_APValueChanged, true);
            app.Bregma_DV.Value = 2.1314;
            app.Lambda_AP = uieditfield(app.ZoomUIFigure, 'numeric');
            app.Lambda_AP.Limits = [0 10];
            app.Lambda_AP.ValueDisplayFormat = '%1.4f';
            app.Lambda_AP.ValueChangedFcn = createCallbackFcn(app, @Bregma_APValueChanged, true);
            app.Lambda_AP.Value = 2.1314;
            app.Lambda_ML = uieditfield(app.ZoomUIFigure, 'numeric');
            app.Lambda_ML.Limits = [0 10];
            app.Lambda_ML.ValueDisplayFormat = '%1.4f';
            app.Lambda_ML.ValueChangedFcn = createCallbackFcn(app, @Bregma_APValueChanged, true);
            app.Lambda_ML.Value = 2.1314;
            app.Lambda_DV = uieditfield(app.ZoomUIFigure, 'numeric');
            app.Lambda_DV.Limits = [0 10];
            app.Lambda_DV.ValueDisplayFormat = '%1.4f';
            app.Lambda_DV.ValueChangedFcn = createCallbackFcn(app, @Bregma_APValueChanged, true);
            app.Lambda_DV.Value = 2.1314;
            app.Midline_DV = uieditfield(app.ZoomUIFigure, 'numeric');
            app.Midline_DV.Limits = [0 10];
            app.Midline_DV.ValueDisplayFormat = '%1.4f';
            app.Midline_DV.ValueChangedFcn = createCallbackFcn(app, @Bregma_APValueChanged, true);
            app.Midline_DV.Value = 2.1314;
            app.Absolute_AP = uieditfield(app.ZoomUIFigure, 'numeric');
            app.Absolute_AP.Limits = [-20 20];
            app.Absolute_AP.ValueDisplayFormat = '%1.4f';
            app.Absolute_AP.Editable = 'off';
            app.Absolute_AP.Value = 2.1314;
            app.Absolute_ML = uieditfield(app.ZoomUIFigure, 'numeric');
            app.Absolute_ML.Limits = [-20 20];
            app.Absolute_ML.ValueDisplayFormat = '%1.4f';
            app.Absolute_ML.Editable = 'off';
            app.Absolute_ML.Value = 2.1314;
            app.Absolute_DV = uieditfield(app.ZoomUIFigure, 'numeric');
            app.Absolute_DV.Limits = [-20 20];
            app.Absolute_DV.ValueDisplayFormat = '%1.4f';
            app.Absolute_DV.Editable = 'off';
            app.Absolute_DV.Value = 2.1314;
            app.BilateralFibersButton = uibutton(app.ZoomUIFigure, 'push');
            app.BilateralFibersButton.ButtonPushedFcn = createCallbackFcn(app, @BilateralFibersButtonPushed, true);
            app.BilateralFibersButton.Text = 'Bilateral Fibers';
        end
    end

    methods (Access = public)

        % Construct app
        function app = zoomfigure(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ZoomUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ZoomUIFigure)
        end
    end
end